classdef GUI_PBE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        INPUTSIGNALPanel           matlab.ui.container.Panel
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel    matlab.ui.control.Label
        SinAmplitude               matlab.ui.control.NumericEditField
        SamplingFrequencyLabel     matlab.ui.control.Label
        SamplingFrequency          matlab.ui.control.NumericEditField
        FrequencyEditField_2Label  matlab.ui.control.Label
        SinFrequency               matlab.ui.control.NumericEditField
        SignalDropDownLabel        matlab.ui.control.Label
        SignalDropDown             matlab.ui.control.DropDown
        AMPLIFIERPanel             matlab.ui.container.Panel
        AmplitudeEditField_3Label  matlab.ui.control.Label
        TriangularAmplitude        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        TriangularFrequency        matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        l                          matlab.ui.control.NumericEditField
        CEditFieldLabel            matlab.ui.control.Label
        c                          matlab.ui.control.NumericEditField
        GainEditFieldLabel         matlab.ui.control.Label
        Gain                       matlab.ui.control.NumericEditField
        TriangularWaveformLabel    matlab.ui.control.Label
        FilterLabel                matlab.ui.control.Label
        RDropDownLabel             matlab.ui.control.Label
        r                          matlab.ui.control.DropDown
        STARTSIMULATIONButton      matlab.ui.control.Button
        SIMULATIONSPanel           matlab.ui.container.Panel
        UIAxes_1                   matlab.ui.control.UIAxes
        ScaleDropDownLabel         matlab.ui.control.Label
        ScaleDropDown              matlab.ui.control.DropDown
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_6                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        CLASSDAMPLIFIERLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        t
        f
        signal
        signal_ft
        waveform
        waveform_ft
        comparator_output
        comparator_output_ft
        output
        output_ft
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTSIMULATIONButton
        function STARTSIMULATIONButtonPushed(app, event)
            
            %PARAMETERS
            
            N = app.NEditField.Value;                           %Number of samples
            Time = N / app.SamplingFrequency.Value;             %Duration of the signal
            app.f = (0:N-1)/Time;                               %Vector of the frequency samples
            app.t = 0:(1/app.SamplingFrequency.Value):Time;     %Vector of the time samples
            app.t = app.t(1:N);
            
            
            %INPUT SIGNAL
            
            %Choses which signal it wants to display
            switch app.SignalDropDown.Value         
                case '1'    %Generates a sin wave at the given frequency
                    app.signal =  app.SinAmplitude.Value*sin(2*pi*app.SinFrequency.Value*app.t);        
                case '2'    %Generates a square wave at the given frequency
                    app.signal = app.SinAmplitude.Value*square(2*pi*app.SinFrequency.Value*app.t);      
                case '3'    %Generates a sawtooth wave at the given frequency
                    app.signal = app.SinAmplitude.Value*sawtooth(2*pi*app.SinFrequency.Value*app.t);    
            end
            
            app.signal_ft = (2/N)*abs(fft(app.signal,N));       %Gives the fft of the input signal
            
            
            %TRIANGULAR WAVEFORM
            
            amplitude = 2 * app.TriangularAmplitude.Value;                          % Peak-to-peak amplitude.
            elementsPerHalfPeriod = (N/(app.TriangularFrequency.Value*Time*2))+1;   
            numberOfPeriods = ceil(app.TriangularFrequency.Value*Time);          % How many replicates of the triangle you want.
            verticalOffset = - amplitude/2;
            
            % Construct one cycle, up and down.
            risingSignal = linspace(0, amplitude, ceil(elementsPerHalfPeriod)+1);
            fallingSignal = linspace(amplitude, 0, ceil(elementsPerHalfPeriod)+1);
            
            % Combine rising and falling sections into one single triangle.
            oneCycle = [risingSignal, fallingSignal(2:end-1)]+verticalOffset;
            
            % Now replicate this cycle several (numberOfPeriods) times.
            app.waveform = repmat(oneCycle,1,numberOfPeriods);
            app.waveform = app.waveform (1:N);
            
            app.waveform_ft = (2/N)*abs(fft(app.waveform, N));                      %Gives the fft of the triangular waveform
            
            
            %COMPARATOR
            
            %Shows both, the input signal and the triangular waveform on the same graph
            plot(app.UIAxes_5, app.t, app.signal);
            hold(app.UIAxes_5, 'on');
            plot(app.UIAxes_5, app.t, app.waveform);
            hold(app.UIAxes_5, 'off');
            xlim(app.UIAxes_5, [0 100/app.TriangularFrequency.Value]);
                        
            
            app.comparator_output = app.signal > app.waveform;                 %Returns 1 if true and 0 otherwise
            app.comparator_output = app.comparator_output * 2 - 1;
            app.comparator_output_ft = (2/N)*abs(fft(app.comparator_output));   %Gives te fft of the comparator output
            
            
            %POWER AMPLIFIER
            
            comparator_amplified = app.comparator_output * app.Gain.Value;      %Amplifies the comparator output
            
            
            %FILTER
            
            R = str2double(app.r.Value);
            L = app.l.Value;
            C = app.c.Value;
            Fs = app.SamplingFrequency.Value;
            
            %Generates the numerator and denominator of the transfer function that forms the filter
            num = [R/(Fs*Fs) 2*R/(Fs*Fs) R/(Fs*Fs)];
            den = [(4*R*L*C+2*L/Fs+R/(Fs*Fs)) (2*R/(Fs*Fs)-8*R*L*C) (4*R*L*C-2*L/Fs+R/(Fs*Fs))];
            
            app.output = filter(num, den, comparator_amplified);        %Gets the signal through the filter
            
            app.output_ft = (2/N)*abs(fft(app.output));                 %Gives the fft of the output signal
            
            delta = zeros(1, N);                                        %Generates a delta function
            delta(1) = 1;
                
            filtre = filter(num, den, delta);                           %Gets the delta function through the filter showing the shape of the filter      
            
            filtre_ft = abs(fft(filtre,N));                             %Gives the fft of the output signal
                        
            
            %Shows both, the filter and the comparator output on the same graph
            semilogx(app.UIAxes_6, app.f, filtre_ft);                   
            hold(app.UIAxes_6, 'on');
            semilogx(app.UIAxes_6, app.f, app.comparator_output_ft);    
            hold(app.UIAxes_6, 'off');
            xlim(app.UIAxes_6, [0 app.SamplingFrequency.Value/2]);
            grid(app.UIAxes_6, 'on');
            
            
            %PLOT SIGNALS
            
            ScaleDropDownValueChanged(app, event);      %Plots all the signals
        end

        % Value changed function: ScaleDropDown
        function ScaleDropDownValueChanged(app, event)
            value = app.ScaleDropDown.Value;
            
            %Plots every signal whether they are in time or in frequency
            if(value == "Time")
                set(app.UIAxes_1, 'XScale', 'linear');                      %Input Signal 
                plot(app.UIAxes_1, app.t, app.signal)               
                xlim(app.UIAxes_1, [0 2/app.SinFrequency.Value]);
                xlabel(app.UIAxes_1, 'Time (s)');
                
                set(app.UIAxes_2, 'XScale', 'linear');                      %Triangular Waveform
                plot(app.UIAxes_2, app.t, app.waveform)                     
                xlim(app.UIAxes_2, [0 50/app.TriangularFrequency.Value]);
                xlabel(app.UIAxes_2, 'Time (s)');
                
                set(app.UIAxes_3, 'XScale', 'linear');                      %Comparator Output
                plot(app.UIAxes_3, app.t, app.comparator_output)            
                xlim(app.UIAxes_3, [0 50/app.TriangularFrequency.Value]);
                xlabel(app.UIAxes_3, 'Time (s)');
                    
                set(app.UIAxes_4, 'XScale', 'linear');                      %Output Signal
                plot(app.UIAxes_4, app.t, app.output)               
                xlim(app.UIAxes_4, [0 2/app.SinFrequency.Value]);
                xlabel(app.UIAxes_4, 'Time (s)');
            else
                set(app.UIAxes_1, 'XScale', 'log');                         %Input Signal 
                plot(app.UIAxes_1, app.f, app.signal_ft)
                xlim(app.UIAxes_1, [0 app.SamplingFrequency.Value/2]);
                xlabel(app.UIAxes_1, 'Frequency-log (Hz)');
                
                set(app.UIAxes_2, 'XScale', 'log');                         %Triangular Waveform
                plot(app.UIAxes_2, app.f, app.waveform_ft)
                xlim(app.UIAxes_2, [0 app.SamplingFrequency.Value/2]);
                xlabel(app.UIAxes_2, 'Frequency-log (Hz)');
                
                set(app.UIAxes_3, 'XScale', 'log');                         %Comparator Output
                plot(app.UIAxes_3, app.f, app.comparator_output_ft)
                xlim(app.UIAxes_3, [0 app.SamplingFrequency.Value/2]);
                xlabel(app.UIAxes_3, 'Frequency-log (Hz)');
                
                set(app.UIAxes_4, 'XScale', 'log');                         %Output Signal
                plot(app.UIAxes_4, app.f, app.output_ft)
                xlim(app.UIAxes_4, [0 app.SamplingFrequency.Value/2]);
                xlabel(app.UIAxes_4, 'Frequency-log (Hz)');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.INPUTSIGNALPanel = uipanel(app.UIFigure);
            app.INPUTSIGNALPanel.Title = 'INPUT SIGNAL';
            app.NEditFieldLabel = uilabel(app.INPUTSIGNALPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.INPUTSIGNALPanel, 'numeric');
            app.NEditField.Value = 655360;
            app.AmplitudeEditFieldLabel = uilabel(app.INPUTSIGNALPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.SinAmplitude = uieditfield(app.INPUTSIGNALPanel, 'numeric');
            app.SinAmplitude.Value = 1;
            app.SamplingFrequencyLabel = uilabel(app.INPUTSIGNALPanel);
            app.SamplingFrequencyLabel.Text = {'Sampling '; 'Frequency'};
            app.SamplingFrequency = uieditfield(app.INPUTSIGNALPanel, 'numeric');
            app.SamplingFrequency.Value = 44100000;
            app.FrequencyEditField_2Label = uilabel(app.INPUTSIGNALPanel);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.SinFrequency = uieditfield(app.INPUTSIGNALPanel, 'numeric');
            app.SinFrequency.Value = 3000;
            app.SignalDropDownLabel = uilabel(app.INPUTSIGNALPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.INPUTSIGNALPanel);
            app.SignalDropDown.Items = {'Sine Wave', 'Square Wave', 'SawTooth Wave'};
            app.SignalDropDown.ItemsData = {'1', '2', '3'};
            app.SignalDropDown.Value = '1';
            app.AMPLIFIERPanel = uipanel(app.UIFigure);
            app.AMPLIFIERPanel.Title = 'AMPLIFIER';
            app.AmplitudeEditField_3Label = uilabel(app.AMPLIFIERPanel);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.TriangularAmplitude = uieditfield(app.AMPLIFIERPanel, 'numeric');
            app.TriangularAmplitude.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.AMPLIFIERPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.TriangularFrequency = uieditfield(app.AMPLIFIERPanel, 'numeric');
            app.TriangularFrequency.Value = 500000;
            app.LEditFieldLabel = uilabel(app.AMPLIFIERPanel);
            app.LEditFieldLabel.Text = 'L';
            app.l = uieditfield(app.AMPLIFIERPanel, 'numeric');
            app.l.Value = 2.2e-05;
            app.CEditFieldLabel = uilabel(app.AMPLIFIERPanel);
            app.CEditFieldLabel.Text = 'C';
            app.c = uieditfield(app.AMPLIFIERPanel, 'numeric');
            app.c.Value = 4.7e-07;
            app.GainEditFieldLabel = uilabel(app.AMPLIFIERPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.Gain = uieditfield(app.AMPLIFIERPanel, 'numeric');
            app.Gain.Value = 10;
            app.TriangularWaveformLabel = uilabel(app.AMPLIFIERPanel);
            app.TriangularWaveformLabel.Text = 'Triangular Waveform';
            app.FilterLabel = uilabel(app.AMPLIFIERPanel);
            app.FilterLabel.Text = 'Filter';
            app.RDropDownLabel = uilabel(app.AMPLIFIERPanel);
            app.RDropDownLabel.Text = 'R';
            app.r = uidropdown(app.AMPLIFIERPanel);
            app.r.Items = {'4', '8', '16'};
            app.r.ItemsData = {'4', '8', '16'};
            app.r.Value = '4';
            app.STARTSIMULATIONButton = uibutton(app.UIFigure, 'push');
            app.STARTSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSIMULATIONButtonPushed, true);
            app.STARTSIMULATIONButton.Text = 'START SIMULATION';
            app.SIMULATIONSPanel = uipanel(app.UIFigure);
            app.SIMULATIONSPanel.Title = 'SIMULATIONS';
            app.UIAxes_1 = uiaxes(app.SIMULATIONSPanel);
            title(app.UIAxes_1, 'Input Signal')
            xlabel(app.UIAxes_1, 'Time (s)')
            ylabel(app.UIAxes_1, 'Amplitude')
            app.ScaleDropDownLabel = uilabel(app.SIMULATIONSPanel);
            app.ScaleDropDownLabel.Text = 'Scale';
            app.ScaleDropDown = uidropdown(app.SIMULATIONSPanel);
            app.ScaleDropDown.Items = {'Time', 'Frequency'};
            app.ScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleDropDownValueChanged, true);
            app.ScaleDropDown.Value = 'Time';
            app.UIAxes_2 = uiaxes(app.SIMULATIONSPanel);
            title(app.UIAxes_2, 'Triangular Waveform')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.UIAxes_3 = uiaxes(app.SIMULATIONSPanel);
            title(app.UIAxes_3, 'Comparator')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Amplitude')
            app.UIAxes_4 = uiaxes(app.SIMULATIONSPanel);
            title(app.UIAxes_4, 'Output Signal')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Amplitude')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Filter')
            xlabel(app.UIAxes_6, 'Frequency (Hz)')
            ylabel(app.UIAxes_6, 'Amplitude')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Comparator Input')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'Amplitude')
            app.CLASSDAMPLIFIERLabel = uilabel(app.UIFigure);
            app.CLASSDAMPLIFIERLabel.Text = 'CLASS-D AMPLIFIER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PBE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end