classdef GUI_PBE2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        VIRTUALINSTRUMENTSLabel     matlab.ui.control.Label
        PrecisionDropDown_2Label    matlab.ui.control.Label
        PrecisionDropDown_2         matlab.ui.control.DropDown
        COMPUTEFRButton             matlab.ui.control.Button
        FundamentalDropDownLabel    matlab.ui.control.Label
        FundamentalDropDown         matlab.ui.control.DropDown
        COMPUTETHDButton            matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        THD1Label                   matlab.ui.control.Label
        COMPUTETHEGAINButton        matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        GainLabel                   matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        GaindBLabel                 matlab.ui.control.Label
        COMPUTEOUTPUTPOWERButton    matlab.ui.control.Button
        Label_4                     matlab.ui.control.Label
        OutputpowerLabel            matlab.ui.control.Label
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField  matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        THD2Label                   matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        THD3Label                   matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: COMPUTETHDButton
        function COMPUTETHDButtonPushed(app, event)
            
            fonamental = str2num(app.FundamentalDropDown.Value);
            if fonamental == 1
              
                temps = 1; %en s
                Fs = app.SamplingFrequencyEditField.Value; %Sampling frequency en Hz
                f1 = 1e3; %Freq del senyal
                mostresTemps = temps*Fs;
                Ts = 1/Fs;
                vectorTemps = 0:Ts:temps; %Time vector
                N = length(vectorTemps); %Signal lenght
                inputTHDFun1 = 1*cos(2*pi*f1*vectorTemps);
                %plot(vectorTemps,inputTHDFun1);
                
                %inputTHDFun1 en f
                nfft=N*6; %Num punts fft
                inputTHDFun1Freq=fft(inputTHDFun1,nfft);
                inputTHDFun1Freq = inputTHDFun1Freq(1:nfft/2); %Part positiva de la fft
                inputTHDFun1Freq = abs(inputTHDFun1Freq)/Fs;
                vectorFreq = (0:nfft/2-1)*Fs/nfft;
                %figure
                %plot(vectorFreq,inputTHDFun1Freq);
                
                %Preparació per introduir el señal al sistema
                BitsperSample = 16;
                play_Obj = audioplayer(inputTHDFun1,Fs,BitsperSample);
                
                %Preparació per rebre el señal del sistema
                NumChanels = 1;
                recorder = audiorecorder(Fs,BitsperSample,NumChanels);
                
                %Transmissió i recepció
                %CANVI
                record(recorder,temps);
                playblocking(play_Obj);
                stop(recorder);
                
                %Tractament de dades rebudes
                audio = getaudiodata(recorder,'single');
                [N,M]=size(audio);
                F = 20e3;
                vectorTemps2 = pi*(F/Fs)*(0:mostresTemps-1);
                T2 = (1/Fs)*linspace(0,N-1,N)';
                %figure
                %plot(T2,audio);
                %figure
                %plot(audio);
                for i=(length(audio))/2:(length(audio)-1)
                    audio2(i-((length(audio))/2)+1,1) = audio(i);
                end
                %figure
                %plot(audio2);
                
                %Pasar el señar a f
                factorNFFT2 = 20;
                nfft2 = length(audio2)*factorNFFT2;
                outputTHDFun1Freq = fft(audio2,nfft2);
                outputTHDFun1Freq = outputTHDFun1Freq(1:nfft2/2); %Part positiva de la fft
                outputTHDFun1Freq = abs(outputTHDFun1Freq)/Fs;
                vectorFreq2 = (0:nfft2/2-1)*Fs/nfft2; 
                %figure
                %plot(vectorFreq2,outputTHDFun1Freq);
                
                %V1
                posicioV1 = fix(f1*(nfft2/Fs))+1;
                
                %Vn
                V = zeros(20,1);
                W = zeros(60,1);
                i = 1;
                while i<21
                    posicioVn = posicioV1*i;
                    h = 1;
                    m =round(length(vectorFreq2)*1e3/(Fs*2));
                    for j = -m:m
                        W(h) = outputTHDFun1Freq(posicioVn+j);
                        h = h + 1;
                        V(i) = max(W);
                    end
                    i = i+1;
                end
                
                %THD
                i = 2;
                numerador = 0;
                while i<20
                    numerador = V(i)^2 + numerador;
                    i = i+1;
                end
                numerador = sqrt(numerador);
                denominador = V(1);
                THDF1 = numerador*100/denominador;
                app.Label.Text = string(THDF1);
            end
            
            if fonamental == 2
                temps = 1; %en s
                Fs = 44100; %Sampling frequency en Hz
                f2 = 2e3; %Freq del senyal
                mostresTemps = temps*Fs;
                Ts = 1/Fs;
                vectorTemps = 0:Ts:temps; %Time vector
                N = length(vectorTemps); %Signal lenght
                inputTHDFun1 = 1*cos(2*pi*f2*vectorTemps);
                %plot(vectorTemps,inputTHDFun1);
            
                %inputTHDFun1 en f
                nfft=N*6; %Num punts fft
                inputTHDFun1Freq=fft(inputTHDFun1,nfft);
                inputTHDFun1Freq = inputTHDFun1Freq(1:nfft/2); %Part positiva de la fft
                inputTHDFun1Freq = abs(inputTHDFun1Freq)/Fs;
                vectorFreq = (0:nfft/2-1)*Fs/nfft; 
                %figure
                %plot(vectorFreq,inputTHDFun1Freq);
            
                %Preparació per introduir el señal al sistema
                BitsperSample = 16;
                play_Obj = audioplayer(inputTHDFun1,Fs,BitsperSample);
            
                %Preparació per rebre el señal del sistema
                NumChanels = 1;
                recorder = audiorecorder(Fs,BitsperSample,NumChanels);
            
                %Transmissió i recepció
                record(recorder,temps);
                playblocking(play_Obj);
                stop(recorder);
            
                %Tractament de dades rebudes
                %Tractament de dades rebudes
                audio = getaudiodata(recorder,'single');
                [N,M]=size(audio);
                F = 20e3;
                vectorTemps2 = pi*(F/Fs)*(0:mostresTemps-1);
                T2 = (1/Fs)*linspace(0,N-1,N)';
                for i=(length(audio))/2:(length(audio)-1)
                    audio2(i-((length(audio))/2)+1,1) = audio(i);
                end
            
                %Pasar el señar a f
                factorNFFT2 = 20;
                nfft2 = length(audio2)*factorNFFT2;
                outputTHDFun1Freq = fft(audio2,nfft2);
                outputTHDFun1Freq = outputTHDFun1Freq(1:nfft2/2); %Part positiva de la fft
                outputTHDFun1Freq = abs(outputTHDFun1Freq)/Fs;
                vectorFreq2 = (0:nfft2/2-1)*Fs/nfft2; 
                %figure
                %plot(vectorFreq2,outputTHDFun1Freq);
                
                %V1
                posicioV1 = fix(f2*(nfft2/Fs))+1;
                
                %Vn
                V = zeros(20,1);
                W = zeros(60,1);
                i = 1;
                while i<11
                    posicioVn = posicioV1*i;
                    h = 1;
                    m =round(length(vectorFreq2)*1e3/(Fs));
                    for j = -m:m
                        W(h) = outputTHDFun1Freq(posicioVn+j);
                        h = h + 1;
                        V(i) = max(W);
                    end
                    i = i+1;
                end
            
                %THD
                i = 2;
                numerador = 0;
                while i<20
                    numerador = V(i)^2 + numerador;
                    i = i+1;
                end
                numerador = sqrt(numerador);
                denominador = V(1);
                THDF2 = numerador*100/denominador;
                app.Label_5.Text = string(THDF2);
            end
            
            if fonamental == 3
                temps = 1; %en s
                Fs = 44100; %Sampling frequency en Hz
                f3 = 3e3; %Freq del senyal
                mostresTemps = temps*Fs;
                Ts = 1/Fs;
                vectorTemps = 0:Ts:temps; %Time vector
                N = length(vectorTemps); %Signal lenght
                inputTHDFun1 = 1*cos(2*pi*f3*vectorTemps);
                %plot(vectorTemps,inputTHDFun1);
            
                %inputTHDFun1 en f
                nfft=N*6; %Num punts fft
                inputTHDFun1Freq=fft(inputTHDFun1,nfft);
                inputTHDFun1Freq = inputTHDFun1Freq(1:nfft/2); %Part positiva de la fft
                inputTHDFun1Freq = abs(inputTHDFun1Freq)/Fs;
                vectorFreq = (0:nfft/2-1)*Fs/nfft; 
                %plot(vectorFreq,inputTHDFun1Freq);
            
                %Preparació per introduir el señal al sistema
                BitsperSample = 16;
                play_Obj = audioplayer(inputTHDFun1,Fs,BitsperSample);
            
                %Preparació per rebre el señal del sistema
                NumChanels = 1;
                recorder = audiorecorder(Fs,BitsperSample,NumChanels);
            
                %Transmissió i recepció
                record(recorder,temps);
                playblocking(play_Obj);
                stop(recorder);
            
                %Tractament de dades rebudes
                audio = getaudiodata(recorder,'single');
                [N,M]=size(audio);
                F = 20e3;
                vectorTemps2 = pi*(F/Fs)*(0:mostresTemps-1);
                T2 = (1/Fs)*linspace(0,N-1,N)';
                for i=(length(audio))/2:(length(audio)-1)
                    audio2(i-((length(audio))/2)+1,1) = audio(i);
                end
            
                %Pasar el señar a f
                factorNFFT2 = 20;
                nfft2 = length(audio2)*factorNFFT2;
                outputTHDFun1Freq = fft(audio2,nfft2);
                outputTHDFun1Freq = outputTHDFun1Freq(1:nfft2/2); %Part positiva de la fft
                outputTHDFun1Freq = abs(outputTHDFun1Freq)/Fs;
                vectorFreq2 = (0:nfft2/2-1)*Fs/nfft2; 
                %figure
                %plot(vectorFreq2,outputTHDFun1Freq);
                
                %V1
                posicioV1 = fix(f3*(nfft2/Fs))+1;
            
                %Vn
                V = zeros(20,1);
                W = zeros(60,1);
                i = 1;
                while i<7
                    posicioVn = posicioV1*i;
                    h = 1;
                    m =round(length(vectorFreq2)*1e3/(Fs));
                    for j = -m:m
                        W(h) = outputTHDFun1Freq(posicioVn+j);
                        h = h + 1;
                        V(i) = max(W);
                    end
                    i = i+1;
                end
            
                %THD
                i = 2;
                numerador = 0;
                while i<20
                    numerador = V(i)^2 + numerador;
                    i = i+1;
                end
                numerador = sqrt(numerador);
                denominador = V(1);
                THDF3 = numerador*100/denominador;
                app.Label_6.Text = string(THDF3);
            end
        end

        % Button pushed function: COMPUTEFRButton
        function COMPUTEFRButtonPushed(app, event)
            
            numFs = str2double(app.PrecisionDropDown_2.Value); %resolució de la FR (baixa = 10, mitja = 50, alta = 100)

            P1 = 0;
            P2 = 0;
            valorsfs1 = [20,40,100,200,400,1e3,2e3,4e3,6e3,9e3,12e3,15e3,18e3,20e3]; %14
            valorsfs2 = [20,30,40,60,80,100,150,200,300,400,500,800,1e3,1500,2e3,3e3,4e3,5e3,6e3,7e3,8e3,9e3,10e3,15e3,18e3,20e3]; %26
            
            if numFs == 1
                for i = 1:length(valorsfs1)
                    temps = 1; %en s
                    Fs = app.SamplingFrequencyEditField.Value; %Sampling frequency en Hz
                    mostresTemps = temps*Fs;
                    Ts = 1/Fs;
                    vectorTemps = 0:Ts:temps; %Time vector
                    N = length(vectorTemps); %Signal lenght
                    inputFRFun = 0;
                    inputFRFun = cos(2*pi*valorsfs1(i)*vectorTemps);
            
                    %inputFRFun en f
                    nfft=N*6; %Num punts fft
                    inputFRFunFreq=fft(inputFRFun,nfft);
                    inputFRFunFreq = inputFRFunFreq(1:nfft/2); %Part positiva de la fft
                    inputFRFunFreq = abs(inputFRFunFreq)/Fs;
                    vectorFreq = (0:nfft/2-1)*Fs/nfft; 
                    %figure
                    %semilogx(vectorFreq,inputFRFunFreq);
            
                    %Preparació per introduir el señal al sistema
                    BitsperSample = 24;
                    play_Obj = audioplayer(inputFRFun,Fs,BitsperSample);
            
                    %Preparació per rebre el señal del sistema
                    NumChanels = 1;
                    recorder = audiorecorder(Fs,BitsperSample,NumChanels);
            
                    %Transmissió i recepció
                    record(recorder,temps);
                    playblocking(play_Obj);
                    stop(recorder);
            
                    %Tractament de dades rebudes
                    audio = getaudiodata(recorder,'single');
                    [N,M]=size(audio);
                    %figure
                    %plot(audio);
                    F = 20e3;
                    vectorTemps2 = pi*(F/Fs)*(0:mostresTemps-1);
                    T2 = (1/Fs)*linspace(0,N-1,N)';
                    %figure
                    %plot(T2,audio(:,1));
                    for m=(length(audio))/2:(length(audio)-1)
                        audio2(m-((length(audio))/2)+1,1) = audio(m);
                    end
                    %figure
                    %plot(audio2);
            
                    %Pasar el señar a f
                    factorPrecisio = 20;
                    nfft2 = length(audio2)*factorPrecisio;
                    outputFRFunFreq = fft(audio2,nfft2); %pq compili
                    outputFRFunFreq = outputFRFunFreq(1:nfft2/2); %Part positiva de la fft
                    outputFRFunFreq = abs(outputFRFunFreq)/Fs;
                    outputFRFunFreq = abs(outputFRFunFreq)*2;
                    vectorFreq2 = (0:nfft2/2-1)*Fs/nfft2; 
                    %figure
                    %semilogx(vectorFreq2,outputFRFunFreq);
            
                    %Seleccio de Freq a l'entrada
                    P1(i) = max(inputFRFunFreq);
            
                    %Seleccio de Freq a la sortida
                    posicioPn = round(valorsfs1(i)*factorPrecisio/2+1);
                    h = 1;
                    for j=-200:200 %Escombrat de +-20Hz
                            P2a(h) = outputFRFunFreq(posicioPn+j);
                            h = h+1;
                            P2(i) = max (P2a);
                    end
                    
                    pause(1);
                end
            end
            
            if numFs == 2
                 for i = 1:length(valorsfs2)
            
                    temps = 1;
                    Fs = 44100; %Sampling frequency en Hz
                    mostresTemps = temps*Fs;
                    Ts = 1/Fs;
                    vectorTemps = 0:Ts:temps; %Time vector
                    N = length(vectorTemps); %Signal lenght
                    inputFRFun = 0;
                    inputFRFun = cos(2*pi*valorsfs2(i)*vectorTemps);
            
                    %inputFRFun en f
                    nfft=N*6; %Num punts fft
                    inputFRFunFreq=fft(inputFRFun,nfft);
                    inputFRFunFreq = inputFRFunFreq(1:nfft/2); %Part positiva de la fft
                    inputFRFunFreq = abs(inputFRFunFreq)/Fs;
                    vectorFreq = (0:nfft/2-1)*Fs/nfft; 
                    %figure
                    %semilogx(vectorFreq,inputFRFunFreq);
            
                    %Preparació per introduir el señal al sistema
                    BitsperSample = 24;
                    play_Obj = audioplayer(inputFRFun,Fs,BitsperSample);
            
                    %Preparació per rebre el señal del sistema
                    NumChanels = 1;
                    recorder = audiorecorder(Fs,BitsperSample,NumChanels);
            
                    %Transmissió i recepció
                    record(recorder,temps);
                    playblocking(play_Obj);
                    stop(recorder);
            
                    %Tractament de dades rebudes
                    audio = getaudiodata(recorder,'single');
                    [N,M]=size(audio);
                    F = 20e3;
                    vectorTemps2 = pi*(F/Fs)*(0:mostresTemps-1);
                    T2 = (1/Fs)*linspace(0,N-1,N)';
                    %figure
                    %plot(T2,audio(:,1));
                    for m=(length(audio))/2:(length(audio)-1)
                        audio2(m-((length(audio))/2)+1,1) = audio(m);
                    end
                    %figure
                    %plot(audio2);
            
                    %Pasar el señar a f
                    factorPrecisio = 20;
                    nfft2 = length(audio2)*factorPrecisio;
                    outputFRFunFreq = fft(audio2,nfft2); %pq compili
                    outputFRFunFreq = outputFRFunFreq(1:nfft2/2); %Part positiva de la fft
                    outputFRFunFreq = abs(outputFRFunFreq)/Fs;
                    outputFRFunFreq = abs(outputFRFunFreq)*2;
                    vectorFreq2 = (0:nfft2/2-1)*Fs/nfft2; 
                    %figure
                    %semilogx(vectorFreq2,outputFRFunFreq);
            
                    %Seleccio de Freq a l'entrada
                    P1(i) = max(inputFRFunFreq);
            
                    %Seleccio de Freq a la sortida
                    posicioPn = round(valorsfs2(i)*factorPrecisio/2+1);
                    h = 1;
                    for j=-200:200 %Escombrat de +-20Hz
                            P2a(h) = outputFRFunFreq(posicioPn+j);
                            h = h+1;
                            P2(i) = max (P2a);
                    end
                    
                    pause(1); %en s
                end
            end
            
            %Cálcul de FR
            FRinDB = 10*log10(P2./P1);
            
            %Interpol·lació
            vectorFRinter = 20:20e3;
            if numFs == 1
                FRinDBinter = interp1(valorsfs1,FRinDB,vectorFRinter,'spline'); %spline, linear, ...
            end
            if numFs == 2
                FRinDBinter = interp1(valorsfs2,FRinDB,vectorFRinter,'spline'); %spline, linear, ...
            end
            
            %Representació
            if numFs == 1
                semilogx(app.UIAxes, valorsfs1, FRinDB);   
            else
                semilogx(app.UIAxes, valorsfs2, FRinDB);
            end
            
            
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [20 20000]);
        end

        % Button pushed function: COMPUTETHEGAINButton
        function COMPUTETHEGAINButtonPushed(app, event)
            temps = 1; %en s
            Fs = app.SamplingFrequencyEditField.Value; %Sampling frequency en Hz
            f1 = 1000; %Freq del senyal
            mostresTemps = temps*Fs;
            Ts = 1/Fs;
            vectorTemps = 0:Ts:temps; %Time vector
            N = length(vectorTemps); %Signal lenght
            input1 = 1*cos(2*pi*f1*vectorTemps);
            %plot(vectorTemps,input1);
            
            %inputTHDFun1 en f
            nfft=N*6; %Num punts fft
            input1Freq=fft(input1,nfft);
            input1Freq = input1Freq(1:nfft/2); %Part positiva de la fft
            input1Freq = abs(input1Freq)/Fs;
            vectorFreq = (0:nfft/2-1)*Fs/nfft; 
            %figure
            %plot(vectorFreq,input1Freq);
            
            %Preparació per introduir el señal al sistema
            BitsperSample = 24;
            play_Obj = audioplayer(input1,Fs,BitsperSample);
            
            %Preparació per rebre el señal del sistema
            NumChanels = 1;
            recorder = audiorecorder(Fs,BitsperSample,NumChanels);
            
            %Transmissió i recepció
            %CANVI
            record(recorder,temps);
            playblocking(play_Obj);
            stop(recorder);
            
            %Tractament de dades rebudes
            audio = getaudiodata(recorder,'single');
            [N,M]=size(audio);
            F = 20e3;
            vectorTemps2 = pi*(F/Fs)*(0:mostresTemps-1);
            T2 = (1/Fs)*linspace(0,N-1,N)';
            for i=(length(audio))/2:(length(audio)-1)
                audio2(i-((length(audio))/2)+1,1) = audio(i);
            end
            
            %Pasar el señar a f
            outputFreq = fft(audio2,nfft); %pq compili
            outputFreq = outputFreq(1:nfft/2); %Part positiva de la fft
            outputFreq = abs(outputFreq)/Fs;
            outputFreq = outputFreq*2;
            vectorFreq2 = (0:nfft/2-1)*Fs/nfft; 
            outputFreq = abs(outputFreq);
            %figure
            %plot(vectorFreq2,outputFreq);
            
            %Seleccio de Freq a l'entrada
            P1 = 0;
            P1a = 0; 
            posicioP1 = fix(f1*(nfft/Fs))+1;
            h = 1;
            for j=-2000:2000
                P1a(h) = input1Freq(posicioP1+j);
                h = h+1;
                P1 = max (P1a);
            end
            
            %Seleccio de Freq a la sortida
            P2 = 0;
            P2a = 0; 
            posicioP2 = fix(f1*(nfft/Fs))+1;
            h = 1;
            for j=-2000:2000
                P2a(h) = outputFreq(posicioP2+j);
                h = h+1;
                P2 = max (P2a);
            end
            
            %Guany
            Guany = P2/P1;
            GuanyendB = 10*log10(Guany);
            
            app.Label_2.Text=string(Guany);
            app.Label_3.Text=string(GuanyendB);
        end

        % Button pushed function: COMPUTEOUTPUTPOWERButton
        function COMPUTEOUTPUTPOWERButtonPushed(app, event)
            temps = 1; %en s
            Fs = app.SamplingFrequencyEditField.Value; %Sampling frequency en Hz
            mostresTemps = temps*Fs;
            f1 = 1e3; %Freq del senyal
            Ts = 1/Fs;
            vectorTemps = 0:Ts:temps; %Time vector
            %N = length(vectorTemps); %Signal lenght, no es fa servir
            inputFun1 = 1*cos(2*pi*f1*vectorTemps);
            %figure
            %plot(vectorTemps,inputFun1);
            
            %Preparació per introduir el señal al sistema
            BitsperSample = 24;
            play_Obj = audioplayer(inputFun1,Fs,BitsperSample);
            
            %Preparació per rebre el señal del sistema
            NumChanels = 1;
            recorder = audiorecorder(Fs,BitsperSample,NumChanels);
            
            %Transmissió i recepció
            %CANVI
            record(recorder,temps+1);
            playblocking(play_Obj);
            pause(1);
            stop(recorder);
            
            %Tractament de dades rebudes
            audio = getaudiodata(recorder,'single');
            [N,M]=size(audio);
            F = 20e3;
            vectorTemps2 = pi*(F/Fs)*(0:mostresTemps-1);
            T2 = (1/Fs)*linspace(0,N-1,N)';
            %figure
            %plot(T2,audio(:,1));
            for i=15800:(15800+(round(length(audio)-1)/2))
                audio2(i-15800+1,1) = audio(i);
            end
            %figure
            %plot(audio2);
            
            %outputPower
            W = 0;
            outputPower1 = 0;
            for i = 1:length(audio2)
                outputPower1 = outputPower1 + (abs(audio2(i)))^2;
            end
            outputPower1 = outputPower1/length(audio2);
            app.Label_4.Text=string(outputPower1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequency Response')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Gain (dB)')
            app.VIRTUALINSTRUMENTSLabel = uilabel(app.UIFigure);
            app.VIRTUALINSTRUMENTSLabel.Text = 'VIRTUAL INSTRUMENTS';
            app.PrecisionDropDown_2Label = uilabel(app.UIFigure);
            app.PrecisionDropDown_2Label.Text = 'Precision';
            app.PrecisionDropDown_2 = uidropdown(app.UIFigure);
            app.PrecisionDropDown_2.Items = {'Medium', 'High'};
            app.PrecisionDropDown_2.ItemsData = {'1', '2'};
            app.PrecisionDropDown_2.Value = '1';
            app.COMPUTEFRButton = uibutton(app.UIFigure, 'push');
            app.COMPUTEFRButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEFRButtonPushed, true);
            app.COMPUTEFRButton.Text = 'COMPUTE FR';
            app.FundamentalDropDownLabel = uilabel(app.UIFigure);
            app.FundamentalDropDownLabel.Text = 'Fundamental';
            app.FundamentalDropDown = uidropdown(app.UIFigure);
            app.FundamentalDropDown.Items = {'@1k', '@2k', '@3k'};
            app.FundamentalDropDown.ItemsData = {'1', '2', '3'};
            app.FundamentalDropDown.Value = '1';
            app.COMPUTETHDButton = uibutton(app.UIFigure, 'push');
            app.COMPUTETHDButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTETHDButtonPushed, true);
            app.COMPUTETHDButton.Text = 'COMPUTE THD';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.THD1Label = uilabel(app.UIFigure);
            app.THD1Label.Text = 'THD1 (%):';
            app.COMPUTETHEGAINButton = uibutton(app.UIFigure, 'push');
            app.COMPUTETHEGAINButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTETHEGAINButtonPushed, true);
            app.COMPUTETHEGAINButton.Text = 'COMPUTE THE GAIN';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.GainLabel = uilabel(app.UIFigure);
            app.GainLabel.Text = 'Gain:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.GaindBLabel = uilabel(app.UIFigure);
            app.GaindBLabel.Text = 'Gain (dB):';
            app.COMPUTEOUTPUTPOWERButton = uibutton(app.UIFigure, 'push');
            app.COMPUTEOUTPUTPOWERButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEOUTPUTPOWERButtonPushed, true);
            app.COMPUTEOUTPUTPOWERButton.Text = {'COMPUTE '; 'OUTPUT POWER'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.OutputpowerLabel = uilabel(app.UIFigure);
            app.OutputpowerLabel.Text = 'Output power:';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = {'Sampling'; ' Frequency'};
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyEditField.Value = 44100;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.THD2Label = uilabel(app.UIFigure);
            app.THD2Label.Text = 'THD2 (%):';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.THD3Label = uilabel(app.UIFigure);
            app.THD3Label.Text = 'THD3 (%):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PBE2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end